classdef RPlidar_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        ConnectBluetoothDeviceMenu     matlab.ui.container.Menu
        DisconnectBluetoothDeviceMenu  matlab.ui.container.Menu
        RoomSizeEditField_2Label       matlab.ui.control.Label
        RoomSizeEditField_2            matlab.ui.control.NumericEditField
        VehicleDirectionDropDownLabel  matlab.ui.control.Label
        VehicleDirectionDropDown       matlab.ui.control.DropDown
        OutputTextAreaLabel            matlab.ui.control.Label
        OutputTextArea                 matlab.ui.control.TextArea
        EnviromentDropDownLabel        matlab.ui.control.Label
        EnviromentDropDown             matlab.ui.control.DropDown
        EditField                      matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        ObstacleplacementLabel         matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        RangeEditFieldLabel            matlab.ui.control.Label
        RangeEditField                 matlab.ui.control.NumericEditField
        EditField_3                    matlab.ui.control.NumericEditField
        EditField_4                    matlab.ui.control.NumericEditField
        Label_2                        matlab.ui.control.Label
        RangeEditField_2Label          matlab.ui.control.Label
        RangeEditField_2               matlab.ui.control.NumericEditField
        EditField_5                    matlab.ui.control.NumericEditField
        EditField_6                    matlab.ui.control.NumericEditField
        Label_3                        matlab.ui.control.Label
        RangeEditField_3Label          matlab.ui.control.Label
        RangeEditField_3               matlab.ui.control.NumericEditField
        DisplayButton                  matlab.ui.control.Button
        DetectionRangeEditFieldLabel   matlab.ui.control.Label
        DetectionRangeEditField        matlab.ui.control.NumericEditField
        Panel                          matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = private)
        Property % Description
        enviroment = '1'
        direction = 2
        roomSize = 20
        detectionRange = 15
        x_square
        y_square
        x_v1
        x_v2
        x_v3
        x_v4
        x_v5
        
        y_v1
        y_v2
        y_v3
        y_v4
        y_v5
        
        theta_square
        rho_square
        pax
        ax
        d
        
        collision_vector
        
        obstRange1
        obstRange2
        obstRange3
        obstRange4
        obstRange5
        obstRange6
        rangeField1
        rangeField2
        rangeField3
        
        start
        rand_ranges
        rand_add
        rand_theta
        rand_theta_rad
        
        
        Zone_A
        Zone_B
        Zone_C
        Zone_D
        Zone_E
        Zone_F
        Zone_G
        Zone_H
        
        collision_angles
        number_of_collisions
        
        instruction
        instructionStr
        
        device = 0
        testStr
        stopStr

  
    end
    
    methods (Access = private)
        
        function [x_square, y_square] = genCatSq(app, size)
        app.x_v1 = ones(1,45) * size;
        app.x_v2 = linspace(size, -size, 90);
        app.x_v3 = ones(1, 90) * (-size);
        app.x_v4 = linspace(-size, size, 90);
        app.x_v5 = ones(1,45) * size;
        x_square = [app.x_v1 app.x_v2 app.x_v3 app.x_v4 app.x_v5];

        app.y_v1 = linspace(0, size, 45);
        app.y_v2 = ones(1,90) * size;
        app.y_v3 = linspace(size, -size, 90);
        app.y_v4 = ones(1,90) * (-size);
        app.y_v5 = linspace(-size, 0, 45);
        y_square = [app.y_v1 app.y_v2 app.y_v3 app.y_v4 app.y_v5];
        end
        
        function d = obstDet(app, range, theta, rho)
        d = zeros(1,360);
            for i = 1:360
                if  abs(rho(i)) <= range
                line_angle = [theta(i) theta(i)];
                line_range = [0 rho(i)];
                polarplot(app.pax, line_angle, line_range, 'r')
                d(i) = 1;
                end
            end

        end


        function u = obstAvoid(app, direction, collision_vector)
        app.collision_angles = find(collision_vector == 1);
        app.number_of_collisions = size(app.collision_angles);
        if app.number_of_collisions == 0
            u = str2double(direction);
            return
        end

        app.Zone_A = true;  % 337.5 - 22.5
        app.Zone_B = true;  % 22.5 - 67.5
        app.Zone_C = true;  % 67.5 - 112.5
        app.Zone_D = true;  % 112.5 - 157.5
        app.Zone_E = true;  % 157.5 - 202.5
        app.Zone_F = true;  % 202.5 - 247.5
        app.Zone_G = true;  % 247.5 - 292.5
        app.Zone_H = true;  % 292.5 - 337.5
    
        for i = 1:app.number_of_collisions(1,2)
            if (app.collision_angles(i) <= 22.5) || (app.collision_angles(i) >= 337.5)
                app.Zone_A = false;
            
            elseif (app.collision_angles(i) <= 67.5) && (app.collision_angles(i) >= 22.5)
                app.Zone_B = false;
                
            elseif (app.collision_angles(i) <= 112.5) && (app.collision_angles(i) >= 67.5)
                app.Zone_C = false;
                
            elseif (app.collision_angles(i) <= 157.5) && (app.collision_angles(i) >= 112.5)
                app.Zone_D = false;
                
            elseif (app.collision_angles(i) <= 202.5) && (app.collision_angles(i) >= 157.5)
                app.Zone_E = false;
                
            elseif (app.collision_angles(i) <= 247.5) && (app.collision_angles(i) >= 202.5)
                app.Zone_F = false;
                
            elseif (app.collision_angles(i) <= 292.5) && (app.collision_angles(i) >= 247.5)
                app.Zone_G = false;
                
            elseif (app.collision_angles(i) <= 337.5) && (app.collision_angles(i) >= 292.5)
                app.Zone_H = false;
                
            end
            
        end


        if direction == '2'
        
            if app.Zone_C == true
                disp("F")
                u = 2;
                
            elseif (app.Zone_B == false) || (app.Zone_A == false)
                if app.Zone_D == true
                    disp("FL")
                    u = 3;
                elseif (app.Zone_D == false) && (app.Zone_E == true)
                    disp("L")
                    u = 4;
                elseif app.Zone_B == true
                    disp ("FR")
                    u = 1;
                elseif app.Zone_A == true
                    disp ("R")
                    u = 0;
                else 
                    disp("Error 001")
                    u = 8;
                    
                end
                
            elseif (app.Zone_D == false) || (app.Zone_E == false)
                if app.Zone_B == true
                    disp("FR")
                    u = 1;
                elseif (app.Zone_B == false) && (app.Zone_A == true)
                    disp("R")
                    u = 0;
                elseif app.Zone_D == true
                    disp ("FL")
                    u = 3;
                elseif app.Zone_E == true
                    disp ("L")
                    u = 4;
                else
                    disp("Error 002")
                    u = 9;
                end
            end
            
            
        elseif direction == '6'
            
            if app.Zone_G == true
                disp("B")
                u = 6;
                
            elseif (app.Zone_H == false) || (app.Zone_A == false)
                if app.Zone_F == true
                    disp("BL")
                    u = 5;
                elseif (app.Zone_F == false) && (app.Zone_E == true)
                    disp("L")
                    u = 4;
                elseif app.Zone_H == true
                    disp ("BR")
                    u = 7;
                elseif app.Zone_A == true
                    disp ("R")
                    u = 0;
                else
                    disp("Error 003")
                    u = 10;
                end
                
            elseif (app.Zone_E == false) || (app.Zone_F == false)
                if app.Zone_H == true
                    disp("BR")
                    u = 7;
                elseif (app.Zone_H == false) && (app.Zone_A == true)
                    disp("R")
                    u = 0;
                elseif app.Zone_F == true
                    disp ("BL")
                    u = 5;
                elseif app.Zone_E == true
                    disp ("L")
                    u = 4;
                else
                    disp("Error 004")
                    u = 11;
                end
            end
            
            
        elseif direction == '0'
            
            if app.Zone_A == true
                disp("R")
                u = 0;
                
            elseif (app.Zone_G == false) || (app.Zone_H == false)
                if app.Zone_B == true
                    disp("FR")
                    u = 1;
                elseif (app.Zone_B == false) && (app.Zone_C == true)
                    disp("F")
                    u = 2;
                elseif app.Zone_H == true
                    disp ("BR")
                    u = 7;
                elseif app.Zone_G == true
                    disp ("B")
                    u = 6;
                else
                    disp("Error 005")
                    u = 12;
                end
                
            elseif (app.Zone_C == false) || (app.Zone_B == false)
                if app.Zone_H == true
                    disp("BR")
                    u = 7;
                elseif (app.Zone_H == false) && (app.Zone_G == true)
                    disp("B")
                    u = 6;
                elseif app.Zone_B == true
                    disp ("FR")
                    u = 1;
                elseif app.Zone_C == true
                    disp ("F")
                    u = 2;
                else
                    disp("Error 006")
                    u = 13;
                end
            end


        elseif direction == '4'
            
            if app.Zone_E == true
                disp("L")
                u = 4;
                
            elseif (app.Zone_F == false) || (app.Zone_G == false)
                if app.Zone_D == true
                    disp("FL")
                    u = 3;
                elseif (app.Zone_D == false) && (app.Zone_C == true)
                    disp("F")
                    u = 2;
                elseif app.Zone_F == true
                    disp ("BL")
                    u = 5;
                elseif app.Zone_G == true
                    disp ("B")
                    u = 6;
                else
                    disp("Error 007")
                    u = 14;
                end
                
            elseif (app.Zone_D == false) || (app.Zone_C == false)
                if app.Zone_F == true
                    disp("BL")
                    u = 5;
                elseif (app.Zone_F == false) && (app.Zone_G == true)
                    disp("B")
                    u = 6;
                elseif app.Zone_D == true
                    disp ("FL")
                    u = 3;
                elseif app.Zone_C == true
                    disp ("F")
                    u = 2;
                else
                    disp("Error 008")
                    u = 15;
                end
            end
            
        end
            
        end
        
        %% translate code to instructions
        
        function translation = codeTranslator(app, instruction)
               switch instruction
                    case 0
                        translation = "Right";
                    case 1
                        translation = "Forward, Right";
                    case 2
                        translation = "Forward";
                    case 3
                        translation = "Forward, Left";
                    case 4
                        translation = "Left";
                    case 5
                        translation = "Backward, Left";
                    case 6
                        translation = "Backward";
                    case 7
                        translation = "Backward, Right";
                    case 8
                        translation = "Error, no path for current direction";
                    case 9
                        translation = "Error, no path for current direction";
                    case 10
                        translation = "Error, no path for current direction";
                    case 11
                        translation = "Error, no path for current direction";
                    case 12
                        translation = "Error, no path for current direction";
                    case 13
                        translation = "Error, no path for current direction";
                    case 14
                        translation = "Error, no path for current direction";
                    case 15
                        translation = "Error, no path for current direction";
                        
                end
        end
        
        function command = sendCommand(app, instruction)
        switch instruction
            case 0
                write(app.device, 'G',"int8")
                pause(1)
                write(app.device, 'H',"int8")
                
            case 1
                write(app.device, 'O',"int8")
                pause(1)
                write(app.device, 'P',"int8")
                    
            case 2
                write(app.device, 'A',"int8")
                pause(1)
                write(app.device, 'B',"int8")
                
            case 3
                % Bör dubbelkollas, blev en snabblösning med QR
                write(app.device, 'T',"int8")
                pause(1)
                write(app.device, 'U',"int8")
                    
            case 4 
                write(app.device, 'E',"int8")
                pause(1)
                write(app.device, 'F',"int8")
                
            case 5
                write(app.device, 'V',"int8")
                pause(1)
                write(app.device, 'W',"int8")
                
            case 6
                write(app.device, 'C',"int8")
                pause(1)
                write(app.device, 'D',"int8")
                
            case 7
                % Bör dubbelkollas, blev en snabblösning
                write(app.device, 'Q',"int8")
                pause(1)
                write(app.device, 'R',"int8")
        end
        command = true;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                app.ObstacleplacementLabel.Enable = 'off';
                app.ObstacleplacementLabel.Visible = 'off';
                app.EditField.Visible = 'off';
                app.EditField.Enable = 'off';
                app.EditField_2.Visible = 'off';
                app.EditField_2.Enable = 'off';
                app.EditField_3.Visible = 'off';
                app.EditField_3.Enable = 'off';
                app.EditField_4.Visible = 'off';
                app.EditField_4.Enable = 'off';
                app.EditField_5.Visible = 'off';
                app.EditField_5.Enable = 'off';
                app.EditField_6.Visible = 'off';
                app.EditField_6.Enable = 'off';
                app.RoomSizeEditField_2.Enable = 'off';
                app.RoomSizeEditField_2Label.Enable = 'off';
                app.Label.Visible = 'off';
                app.Label_2.Visible = 'off';
                app.Label_3.Visible = 'off';
                app.RangeEditField.Visible = 'off';
                app.RangeEditFieldLabel.Visible = 'off';
                app.RangeEditField_2.Visible = 'off';
                app.RangeEditField_2Label.Visible = 'off';
                app.RangeEditField_3.Visible = 'off';
                app.RangeEditField_3Label.Visible = 'off';
        end

        % Value changed function: VehicleDirectionDropDown
        function VehicleDirectionDropDownValueChanged(app, event)
            app.direction = app.VehicleDirectionDropDown.Value;
            
        end

        % Value changed function: EnviromentDropDown
        function EnviromentDropDownValueChanged(app, event)
            app.enviroment = app.EnviromentDropDown.Value;
            
            if app.enviroment == '2'
                app.ObstacleplacementLabel.Enable = 'on';
                app.ObstacleplacementLabel.Visible = 'on';
                app.EditField.Visible = 'on';
                app.EditField.Enable = 'on';
                app.EditField_2.Visible = 'on';
                app.EditField_2.Enable = 'on';
                app.EditField_3.Visible = 'on';
                app.EditField_3.Enable = 'on';
                app.EditField_4.Visible = 'on';
                app.EditField_4.Enable = 'on';
                app.EditField_5.Visible = 'on';
                app.EditField_5.Enable = 'on';
                app.EditField_6.Visible = 'on';
                app.EditField_6.Enable = 'on';
                app.RoomSizeEditField_2.Enable = 'on';
                app.RoomSizeEditField_2Label.Enable = 'on';
                app.Label.Visible = 'on';
                app.Label_2.Visible = 'on';
                app.Label_3.Visible = 'on';
                app.RangeEditField.Visible = 'on';
                app.RangeEditFieldLabel.Visible = 'on';
                app.RangeEditField_2.Visible = 'on';
                app.RangeEditField_2Label.Visible = 'on';
                app.RangeEditField_3.Visible = 'on';
                app.RangeEditField_3Label.Visible = 'on';
            
            elseif app.enviroment == '3'
                app.ObstacleplacementLabel.Enable = 'off';
                app.ObstacleplacementLabel.Visible = 'off';
                app.EditField.Visible = 'off';
                app.EditField.Enable = 'off';
                app.EditField_2.Visible = 'off';
                app.EditField_2.Enable = 'off';
                app.EditField_3.Visible = 'off';
                app.EditField_3.Enable = 'off';
                app.EditField_4.Visible = 'off';
                app.EditField_4.Enable = 'off';
                app.EditField_5.Visible = 'off';
                app.EditField_5.Enable = 'off';
                app.EditField_6.Visible = 'off';
                app.EditField_6.Enable = 'off';
                app.RoomSizeEditField_2.Enable = 'off';
                app.RoomSizeEditField_2Label.Enable = 'off';
                app.Label.Visible = 'off';
                app.Label_2.Visible = 'off';
                app.Label_3.Visible = 'off';
                app.RangeEditField.Visible = 'off';
                app.RangeEditFieldLabel.Visible = 'off';
                app.RangeEditField_2.Visible = 'off';
                app.RangeEditField_2Label.Visible = 'off';
                app.RangeEditField_3.Visible = 'off';
                app.RangeEditField_3Label.Visible = 'off';
            end
            
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
            
            
            app.pax = polaraxes(app.Panel);
            
            
            if app.enviroment == '2'


                [app.x_square, app.y_square] = genCatSq(app, app.roomSize);
                [app.theta_square, app.rho_square] = cart2pol(app.x_square, app.y_square);
                
                if app.obstRange2 ~= 0
                    app.rho_square(app.obstRange2 : app.obstRange1) = app.rangeField1;
                end
                
                if app.obstRange4 ~= 0
                    app.rho_square(app.obstRange4 : app.obstRange3) = app.rangeField2;
                end
                
                if app.obstRange6 ~= 0
                    app.rho_square(app.obstRange6 : app.obstRange5) = app.rangeField3;
                end
                
                polarscatter(app.pax, app.theta_square, app.rho_square, 'b', '.')
                app.pax.NextPlot = 'add';
                
                app.collision_vector = obstDet(app, app.detectionRange, app.theta_square, app.rho_square);
                polaraxes(app.pax)
                app.pax.ThetaColor = 'w';
                app.pax.RColor = 'w';
                app.pax.Color = [0.19,0.21,0.30];
                
                
                app.pax.NextPlot = 'replaceall';
                
                app.instruction = obstAvoid(app, app.direction, app.collision_vector);
                
                % Send command to robot
                if app.device ~= 0
                sendCommand(app, app.instruction);
                end
                
                app.instruction = codeTranslator(app, app.instruction);
      
                app.OutputTextArea.Value = app.instruction;


            elseif app.enviroment == '3'
                
                app.start = randi([15, 20],1,1);
                app.rand_ranges = zeros(1, 360);
                app.rand_ranges(1) = app.start;

                for i = 2:360
                    app.rand_add = randi([-1, 1], 1, 1);
                    app.rand_ranges(i) = app.rand_ranges(i-1) + app.rand_add;
    
                end

                app.rand_theta = linspace(0, 360, 360);
                app.rand_theta_rad = deg2rad(app.rand_theta);
                polarscatter(app.pax, app.rand_theta_rad, app.rand_ranges, '.')
                
                app.pax.NextPlot = 'add';
                
                app.collision_vector = obstDet(app, app.detectionRange, app.rand_theta_rad, app.rand_ranges);

                polaraxes(app.pax)
                app.pax.ThetaColor = 'w';
                app.pax.RColor = 'w';
                app.pax.Color = [0.19,0.21,0.30];
                
                
                app.pax.NextPlot = 'replaceall';
                
                % Obstacle avoidance function
                app.instruction = obstAvoid(app, app.direction, app.collision_vector);
                
                % Send command to robot
                if app.device ~= 0
                sendCommand(app, app.instruction);
                end
                
                % "Error" code translator for text output
                app.instruction = codeTranslator(app, app.instruction);
                app.OutputTextArea.Value = app.instruction;
                
            
            end 
            
        end

        % Value changed function: RoomSizeEditField_2
        function RoomSizeEditField_2ValueChanged(app, event)
            app.roomSize = app.RoomSizeEditField_2.Value;
            
        end

        % Value changed function: DetectionRangeEditField
        function DetectionRangeEditFieldValueChanged(app, event)
            app.detectionRange = app.DetectionRangeEditField.Value;
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.obstRange1 = app.EditField.Value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.obstRange2 = app.EditField_2.Value;
            
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            app.obstRange3 = app.EditField_3.Value;
            
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            app.obstRange4 = app.EditField_4.Value;
            
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            app.obstRange5 = app.EditField_5.Value;
            
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            app.obstRange6 = app.EditField_6.Value;
            
        end

        % Value changed function: RangeEditField
        function RangeEditFieldValueChanged(app, event)
            app.rangeField1 = app.RangeEditField.Value;
            
        end

        % Value changed function: RangeEditField_2
        function RangeEditField_2ValueChanged(app, event)
            app.rangeField2 = app.RangeEditField_2.Value;
            
        end

        % Value changed function: RangeEditField_3
        function RangeEditField_3ValueChanged(app, event)
            app.rangeField3 = app.RangeEditField_3.Value;
            
        end

        % Value changed function: OutputTextArea
        function OutputTextAreaValueChanged(app, event)
           
            
        end

        % Menu selected function: ConnectBluetoothDeviceMenu
        function ConnectBluetoothDeviceMenuSelected(app, event)
            app.device = bluetooth("raspberrypi", 1);
        end

        % Menu selected function: DisconnectBluetoothDeviceMenu
        function DisconnectBluetoothDeviceMenuSelected(app, event)

            clear app.device
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ConnectBluetoothDeviceMenu = uimenu(app.Menu);
            app.ConnectBluetoothDeviceMenu.MenuSelectedFcn = createCallbackFcn(app, @ConnectBluetoothDeviceMenuSelected, true);
            app.ConnectBluetoothDeviceMenu.Text = 'Connect Bluetooth Device';
            app.DisconnectBluetoothDeviceMenu = uimenu(app.Menu);
            app.DisconnectBluetoothDeviceMenu.MenuSelectedFcn = createCallbackFcn(app, @DisconnectBluetoothDeviceMenuSelected, true);
            app.DisconnectBluetoothDeviceMenu.Text = 'Disconnect Bluetooth Device';
            app.RoomSizeEditField_2Label = uilabel(app.UIFigure);
            app.RoomSizeEditField_2Label.Text = 'Room Size';
            app.RoomSizeEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.RoomSizeEditField_2.ValueChangedFcn = createCallbackFcn(app, @RoomSizeEditField_2ValueChanged, true);
            app.RoomSizeEditField_2.Value = 20;
            app.VehicleDirectionDropDownLabel = uilabel(app.UIFigure);
            app.VehicleDirectionDropDownLabel.Text = 'Vehicle Direction';
            app.VehicleDirectionDropDown = uidropdown(app.UIFigure);
            app.VehicleDirectionDropDown.Items = {'-', 'Forward', 'Backward', 'Right', 'Left', ''};
            app.VehicleDirectionDropDown.ItemsData = {'10', '2', '6', '0', '4'};
            app.VehicleDirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @VehicleDirectionDropDownValueChanged, true);
            app.VehicleDirectionDropDown.Value = '10';
            app.OutputTextAreaLabel = uilabel(app.UIFigure);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.UIFigure);
            app.OutputTextArea.ValueChangedFcn = createCallbackFcn(app, @OutputTextAreaValueChanged, true);
            app.EnviromentDropDownLabel = uilabel(app.UIFigure);
            app.EnviromentDropDownLabel.Text = 'Enviroment';
            app.EnviromentDropDown = uidropdown(app.UIFigure);
            app.EnviromentDropDown.Items = {'-', 'Room', 'Cave'};
            app.EnviromentDropDown.ItemsData = {'1', '2', '3'};
            app.EnviromentDropDown.ValueChangedFcn = createCallbackFcn(app, @EnviromentDropDownValueChanged, true);
            app.EnviromentDropDown.Value = '1';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.ObstacleplacementLabel = uilabel(app.UIFigure);
            app.ObstacleplacementLabel.Tooltip = {'Place an obstacle in the room by entering a distance between 1 and 360'; ' and then pick the range'};
            app.ObstacleplacementLabel.Text = 'Obstacle placement';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '-';
            app.RangeEditFieldLabel = uilabel(app.UIFigure);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.RangeEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '-';
            app.RangeEditField_2Label = uilabel(app.UIFigure);
            app.RangeEditField_2Label.Text = 'Range';
            app.RangeEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.RangeEditField_2.ValueChangedFcn = createCallbackFcn(app, @RangeEditField_2ValueChanged, true);
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '-';
            app.RangeEditField_3Label = uilabel(app.UIFigure);
            app.RangeEditField_3Label.Text = 'Range';
            app.RangeEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.RangeEditField_3.ValueChangedFcn = createCallbackFcn(app, @RangeEditField_3ValueChanged, true);
            app.DisplayButton = uibutton(app.UIFigure, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
            app.DetectionRangeEditFieldLabel = uilabel(app.UIFigure);
            app.DetectionRangeEditFieldLabel.Text = 'Detection Range';
            app.DetectionRangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.DetectionRangeEditField.ValueChangedFcn = createCallbackFcn(app, @DetectionRangeEditFieldValueChanged, true);
            app.DetectionRangeEditField.Value = 15;
            app.Panel = uipanel(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'LOA v1.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RPlidar_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end